classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        StartSimButton    matlab.ui.control.Button
        StopSimButton     matlab.ui.control.Button
        FastSLAMGUILabel  matlab.ui.control.Label
        UIAxes2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stop = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimButton
        function StartSimButtonPushed(app, event)
            p = load_waypoints();
            map = load_map();
            
            nSplines = size(p,2)-3;
            hold(app.UIAxes,"on")
            hold(app.UIAxes2, "on")
            axis(app.UIAxes,[-1 13 -1 11])
            axis(app.UIAxes2,[-1 13 -1 11])
            for idx = 1:size(map,1)
                wall = map(idx,:);
                x = wall(1:2);
                y = wall(3:4);
                plot(app.UIAxes,x,[y(1), y(1)],'k', 'LineWidth', 2);
                plot(app.UIAxes,x,[y(2), y(2)],'k', 'LineWidth', 2);
                plot(app.UIAxes,[x(1), x(1)],y,'k', 'LineWidth', 2);
                plot(app.UIAxes,[x(2), x(2)],y,'k', 'LineWidth', 2);
            end
            
            for i = 1:nSplines
                p0 = p(:,i);
                p1 = p(:,i+1);
                p2 = p(:,i+2);
                p3 = p(:,i+3);
                for t = 0.05:0.1:0.95
                    [pose,vel] = catmull_spline(p0, p1, p2, p3, t);
                    v = vecnorm(vel(1:2));  % TODO: Insert noise
                    omega = vel(3);  % TODO: Insert noise
                    % Plotting
                    scatter(app.UIAxes, pose(1),pose(2),'Marker', 'x', 'MarkerEdgeColor', 'k');
                    scatter(app.UIAxes2, pose(1),pose(2),'Marker', 'x', 'MarkerEdgeColor', 'k');
                    z = scan_sim(pose,map);
                    z = z(:,~any(isnan(z) | isinf(z),1));  % Filter out invalid measurements.
                    z(2,:) = mod(z(2,:) + pose(3) + pi,2*pi) - pi;
                    scan_pos = [pose(1) + cos(z(2,:)).*z(1,:);pose(2) + sin(z(2,:)).*z(1,:)];
                    scan_plt = scatter(app.UIAxes, scan_pos(1,:),scan_pos(2,:), 'filled', 'MarkerEdgeColor',[0 0 0],...
                          'MarkerFaceColor',[1 .0 .0],'LineWidth',0.01);
                    scatter(app.UIAxes2, scan_pos(1,:),scan_pos(2,:), 'filled', 'MarkerEdgeColor',[0 0 0],...
                          'MarkerFaceColor',[.0 .0 .0],'LineWidth',0.01);
                    pause(0.05);
                    delete(scan_plt)
                    if app.stop == true
                        break
                    end
                end
                if app.stop == true
                    break
                end
            end
            app.stop = false;
        end

        % Button pushed function: StopSimButton
        function StopSimButtonPushed(app, event)
            app.stop = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map and Robot Position')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StartSimButton = uibutton(app.UIFigure, 'push');
            app.StartSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimButtonPushed, true);
            app.StartSimButton.Text = 'Start Sim';
            app.StopSimButton = uibutton(app.UIFigure, 'push');
            app.StopSimButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimButtonPushed, true);
            app.StopSimButton.Text = 'Stop Sim';
            app.FastSLAMGUILabel = uilabel(app.UIFigure);
            app.FastSLAMGUILabel.Text = 'FastSLAM GUI';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Map and Robot Position')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end